classdef InteractiveFourierSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveFourierSeriesUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        PlotsPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes1                       matlab.ui.control.UIAxes
        ControlsPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        NoteNotalloftheFouriercoefficientsareshownLabel  matlab.ui.control.Label
        FourierSeriesDefinitionPanel  matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        EqAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
        Ns = 200; % Number of points in the spline
        Nf = 200; % Number of points in the fourier series
        Nknots = 12; % Number of draggable spline knots
        lastMode = 5; % The last mode in the fourier series that is shown
        P = 1; % Period (determines the axis limits)
        eqColor = [0 0 0]

        % Plot objects
        plotMarkers % The moveable plot markers
        fcosMarkers
        fsinMarkers
        splineLine % Spline plot (adjusts as the plot markers are moved)
        spectrumLinesA % stem plot for spectrum coeffs (a)
        spectrumLinesB % stem plot for spectrum coeffs (b)
        colors % The colors used for different lines

        % Math objects
        fbcos % cosine basis
        fbsin % sine basis
        tb % time array for the cosine/sine bais
        omegan % angular frequencies
        freq 

        %#ok<*INUSL> 
        %#ok<*INUSD> 
    end
    
    methods (Access = private)
        %% These functions create the line plot
        function setUpMarkers(app)
            cla(app.UIAxes1)
            cla(app.UIAxes2)
            cla(app.UIAxes3)

            % Create plot markers and lines 
            dx = app.P/(app.Nknots);
            x = 0:dx:dx*(app.Nknots-1);
            y = 0*x;

           
            % AXIS 1: time series
            hold(app.UIAxes1,'on')
            % Add the line plot
            app.splineLine = plot(app.UIAxes1,0,0,'linewidth',1,'color',app.colors(1,:));
            for k = 1:numel(x)
                app.plotMarkers{k} = DraggablePoint2D(app.UIAxes1,...
                                        x(k),y(k),@app.timeMarkerMoved,2);
                set(app.plotMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(1,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(app.UIAxes1,'off')
            

            % AXIS 2: cosine coefficients (a)
            % a (cos) coefficients
            x = linspace(0,app.P,app.Nf/2);
            hold(app.UIAxes2,'on')
            % Create stem plot
            app.spectrumLinesA = stem(app.UIAxes2,app.freq,app.freq*0,...
                'linewidth',1,'color',app.colors(3,:),'marker','none');
            % Add markers
            for k = 1:numel(x)
                app.fcosMarkers{k} = DraggablePoint2D(app.UIAxes2,...
                                        app.freq(k),0,@app.fourierMarkerMoved,2);
                set(app.fcosMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(3,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(app.UIAxes2,'off')

            % AXIS 3: sine coefficients (b)
            hold(app.UIAxes3,'on')
            % Create stem plot
            app.spectrumLinesB = stem(app.UIAxes3,app.freq,app.freq*0,...
                'linewidth',1,'color',app.colors(3,:),'marker','none');
            % Add markers
            for k = 1:numel(x)
                app.fsinMarkers{k} = DraggablePoint2D(app.UIAxes3,...
                                        app.freq(k),0,@app.fourierMarkerMoved,2);
                set(app.fsinMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(3,:),...
                     'color',[0.8,0.8,0.8]);   
                if(k == 1)
                    % Don't use the b_0, since sin(0) = 0
                    app.fsinMarkers{k}.lineHandle.Visible = false;
                end
            end
            hold(app.UIAxes3,'off')

            % Update the spectrum axes
            xlim(app.UIAxes2,[0,app.lastMode])
            xlim(app.UIAxes3,[0,app.lastMode])
            xticks(app.UIAxes2,0:app.lastMode)
            xticks(app.UIAxes3,0:app.lastMode)

            % Change graph appearance
            xlabel(app.UIAxes1,'$t$ (s)','interpreter','latex')
            ylabel(app.UIAxes1,'$y$','interpreter','latex')
            xlabel(app.UIAxes2,'Fourier mode $(n)$','interpreter','latex')
            ylabel(app.UIAxes2,'$a_n$ (cosine coef)','interpreter','latex')
            xlabel(app.UIAxes3,'Fourier mode $(n)$','interpreter','latex')
            ylabel(app.UIAxes3,'$b_n$ (sine coef)','interpreter','latex')

            % Update the plots
            app.timeMarkerMoved
        end


        % Executes when a time series marker is moved
        function timeMarkerMoved(app,xp,yp)
            [x,y] = pullTimeData(app);
            % Repeat the points for an extra period to ensure periodicity
            xext = [x-app.P;x;x+app.P];
            yext = [y;y;y];
            [xs,ys] = genSpline(app,xext,yext);

            [a,b] = fourierCoef(app,xs,ys);
            [xf,yf] = evalFourier(app,a,b);

            updateTimeSeries(app,xf,yf)
            updateSpectrum(app,a,b)
            updateSpectrumMarkers(app,a,b)

        end

        % Executes when a fourier coefficient marker is moved
        function fourierMarkerMoved(app,xp,yp)
            [a,b] = pullFourierMarkers(app);
            [xf,yf] = evalFourier(app,a,b);
                  
            % Run through the spline machine for consistency
            [xs,ys] = genSpline(app,xf',yf');
            [a,b] = fourierCoef(app,xs,ys);
            [xf,yf] = evalFourier(app,a,b);


            updateTimeSeries(app,xf,yf)
            updateTimeSeriesMarkers(app,xf,yf)
            updateSpectrum(app,a,b)

        end

        function [a,b] = pullFourierMarkers(app)
            a = zeros(1,numel(app.fcosMarkers));
            b = zeros(1,numel(app.fsinMarkers));
                for k = 1:numel(app.fcosMarkers)
                    [~,a(k)] = app.fcosMarkers{k}.getPos();
                    [~,b(k)] = app.fsinMarkers{k}.getPos();
                end
        end

        % Pulls the data from the plot markers 
        function [x,y] = pullTimeData(app)
            x = zeros(numel(app.plotMarkers),1);
            y = x;
            for k = 1:numel(app.plotMarkers)
                [xp,yp] = app.plotMarkers{k}.getPos();
                x(k) = xp;
                y(k) = yp;
            end
            [x,idx] = unique(x);
            y = y(idx);
        end


        % Updates the time series plot
        function updateTimeSeries(app,xs,ys)
            app.splineLine.XData = xs;
            app.splineLine.YData = ys;
        end

        function updateTimeSeriesMarkers(app,xs,ys)
            for k = 1:numel(app.plotMarkers)
                xk = app.plotMarkers{k}.lineHandle.XData;
                yk = interp1(xs,ys,xk);
                app.plotMarkers{k}.lineHandle.YData = yk;
            end
        end

        % Updates the spectrum plots
        function updateSpectrum(app,a,b)
            app.spectrumLinesA.YData = a;
            app.spectrumLinesB.YData = b;
        end
        function updateSpectrumMarkers(app,a,b)
            for k = 1:numel(app.fsinMarkers)
                app.fcosMarkers{k}.lineHandle.YData = a(k);
                app.fsinMarkers{k}.lineHandle.YData = b(k);
            end
        end

        % Reset to zero
        function resetMarkers(app)
            % reset fourier series
            zeroValues = zeros(1,numel(app.fsinMarkers));
            updateSpectrumMarkers(app,zeroValues,zeroValues)
            updateSpectrum(app,zeroValues,zeroValues)

            % reset time series
            updateTimeSeriesMarkers(app,[-1 2],[0 0])
            [xf,yf] = evalFourier(app,zeroValues,zeroValues);
            updateTimeSeries(app,xf,yf)
        end


        %% Math functions
        % Creates the spline
        function [xs,ys] = genSpline(app,x,y)
            xs = linspace(min(x),max(x),app.Ns);
            ys = spline(x,y,xs);
        end
        
        % Compute the fourier series basis
        function [fbcos, fbsin, t, omegan, freq] = fbasis(app,P,N) 
            % P is the period
            % N is the number of points
            dt = P/(N+1);
            t = 0:dt:dt*N;
            Nterms = round(N/2);
            omega = 2*pi/P;
            omegan = omega.*(0:(Nterms-1))';
            freq = omegan/(2*pi);
            omegatn = t.*omegan;
            fbcos = cos(omegatn);
            fbsin = sin(omegatn);
        end

        % Evaluate Fourier series
        function [xf,yf] = evalFourier(app,a,b)
            % Compute the fourier series
            yFourier = a*app.fbcos + b*app.fbsin;
            xf = linspace(app.tb(1),app.tb(end),app.Ns);
            yf = spline(app.tb,yFourier,xf);           
            xf(end+1) = xf(1)+app.P;
            yf(end+1) = yf(1);
        end

        % Compute the Fourier series coefficients
        function [a,b] = fourierCoef(app,tin,yin)
            % Resample y at the correct number of points
            y = spline(tin,yin,app.tb);
            N = app.Nf;
            F = fft(y);
            % Fourier series coefficients
            a0 = real(F(1))/N;
            b0 = 0;
            Nterms = round(N/2);
            FHalf = F(2:Nterms);
            a = [a0,2/N*real(FHalf)];
            b = [b0,-2/N*imag(FHalf)];
        end


        %% Controls functions

        function createEquationAxes(app)
            % Adjust the equation axes
            axis(app.EqAxes,"off")
            disableDefaultInteractivity(app.EqAxes)
            axtoolbar(app.EqAxes,{});
            eq = "$$f(t) = \frac{a_0}{2} + \sum_{n = 1}^{N} a_n \cos( 2 \pi n t ) + b_n \sin( 2 \pi n t )$$";
            cla(app.EqAxes)
            text(app.EqAxes,0.48,0.5,eq,"color",app.eqColor,"Interpreter","latex",...
                "HorizontalAlignment","center",'FontSize',14)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.colors = lines(4);

            % Set up the fourier basis
            [app.fbcos, app.fbsin, app.tb, app.omegan, app.freq] = app.fbasis(app.P,app.Nf);

            % Create the markers
            app.setUpMarkers()

            % Create equation
            app.createEquationAxes


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetMarkers(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveFourierSeriesUIFigure = uifigure('Visible', 'off');
            app.InteractiveFourierSeriesUIFigure.Name = 'Interactive Fourier Series';
            app.GridLayout = uigridlayout(app.InteractiveFourierSeriesUIFigure);
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsPanel);
            app.EqAxes = uiaxes(app.GridLayout2);
            app.EqAxes.Visible = 'off';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FourierSeriesDefinitionPanel = uipanel(app.GridLayout2);
            app.FourierSeriesDefinitionPanel.Title = 'Fourier Series Definition';
            app.NoteNotalloftheFouriercoefficientsareshownLabel = uilabel(app.GridLayout2);
            app.NoteNotalloftheFouriercoefficientsareshownLabel.Text = {'Note:'; '• Not all of the Fourier coefficients are shown.'; '• The function is estimated with splines.'};
            app.PlotsPanel = uipanel(app.GridLayout);
            app.PlotsPanel.Title = 'Plots';
            app.GridLayout3 = uigridlayout(app.PlotsPanel);
            app.UIAxes1 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes1, 't')
            ylabel(app.UIAxes1, 'y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XLim = [0 1];
            app.UIAxes1.YLim = [-2 2];
            app.UIAxes1.ZLim = [0 1];
            app.UIAxes2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 8];
            app.UIAxes2.YLim = [-1 1];
            app.UIAxes3 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 8];
            app.UIAxes3.YLim = [-1 1];
            % Show the figure after all components are created
            app.InteractiveFourierSeriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteractiveFourierSeries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveFourierSeriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveFourierSeriesUIFigure)
        end
    end
end