classdef PhaseMagnitude < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhaseandMagnitudeUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TimeDomainPhaseandMagnitudeSeriesPanel  matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        DefinitionPanelMagPhase    matlab.ui.container.Panel
        EqAxesMagPhase             matlab.ui.control.UIAxes
        UIAxesMagPhase             matlab.ui.control.UIAxes
        TimeDomainSineandCosineSeriesPanel  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        DefinitionPanelSineCosine  matlab.ui.container.Panel
        EqAxesSineCosine           matlab.ui.control.UIAxes
        UIAxesSineCosine           matlab.ui.control.UIAxes
        FrequencyDomainAandPanel   matlab.ui.container.Panel
        GridLayout6                matlab.ui.container.GridLayout
        NSpinnerMagPhase           matlab.ui.control.Spinner
        NSpinner_2Label            matlab.ui.control.Label
        ClearButtonMagPhase        matlab.ui.control.Button
        UIAxes_phin                matlab.ui.control.UIAxes
        UIAxes_An                  matlab.ui.control.UIAxes
        FrequencyDomainaandbPanel  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        ClearButtonSineCosine      matlab.ui.control.Button
        NSpinnerSineCosine         matlab.ui.control.Spinner
        NLabel                     matlab.ui.control.Label
        UIAxes_an                  matlab.ui.control.UIAxes
        UIAxes_bn                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
               
        P = 1; % Period (determines the axis limits)
        Nmodes % Number of modes (set by spinner)
        Nt = 250; % Number of temporal points

        reLims = 2*[-1,1] % Real axis limits

        lwSeries = 1.25; % Linewidth of time series

        % Colors
        timeColors = [     0    0.4470    0.7410;
                      0.7350    0.1000    0.2500;
                      0.4940    0.1840    0.5560]+0.1;
        traceLineColors = [      0    0.3470    0.5410;
                            0.4350    0.0500    0.1500]+0.1;
        coefColors = [0.9290    0.6940    0.1250;
                      0.9290    0.6940    0.1250;
                      0.9290    0.6940    0.1250;
                      0.9290    0.6940    0.1250];

        % Plot objects
        markers_an 
        markers_bn
        
        plot_an       % Stem plot for an
        plot_bn       % Stem plot for bn
        
        realPlot1     % First time series plot

        % Math objects
        nvec % Vector of mode indices
        fbcos % Cosine basis
        fbsin % Sine basis
        tvec % time array 

        %#ok<*INUSL> 
        %#ok<*INUSD> 
    end
    
    methods (Access = private)
        %% These functions set up the plots, etc.

        function setUpSinCos(app)
           
            % Compute the basis functions
            Nfreq1 = app.NSpinnerSineCosine.Value;
            [app.fbcos, app.fbsin, app.tvec, app.nvec] = sinCosBasis(app,Nfreq1,app.Nt,app.P);

            % Set up an axis
            anvec = 0:Nfreq1;
            color = app.coefColors(1,:);
            [app.markers_an,app.plot_an] = app.setUpFreqAxes(app.UIAxes_an,...
                anvec,color,app.markers_an,app.plot_an,"$n$","$a_n$",@app.coef1Moved);

            % Set up bn axis
            bnvec = 0:Nfreq1;
            color = app.coefColors(1,:);
            [app.markers_bn,app.plot_bn] = app.setUpFreqAxes(app.UIAxes_bn,...
                bnvec,color,app.markers_bn,app.plot_bn,"$n$","$b_n$",@app.coef1Moved);
            app.markers_bn{1}.lineHandle.Visible = "off";

            % Set up first real axes
            app.realPlot1 = setUpRealAxes(app,app.UIAxesSineCosine,"$t$","$f$");
 

            eq = "$$f(t) = \frac{a_0}{2}  + \sum_{n=1}^\infty a_n \cos\left(" + ...
                " \frac{ n \pi } {L}  t \right) + b_n \sin \left( \frac{ n \pi } {L}  t \right)$$";
            app.setupEquationAxes(app.EqAxesSineCosine,eq)

        end


        function [markers,lines] = setUpFreqAxes(app,UIAxes,nvec,color,...
                markers,lines,xLbl,yLbl,callbackFunc)
            
            cla(UIAxes)
            markers = {};

            % Add plots to axis and save the handles
            hold(UIAxes,'on')
            % Create stem plot
            lines = stem(UIAxes,nvec,nvec*0,...
                'linewidth',1,'color',color,'marker','none');
            % Add markers
            for k = 1:numel(nvec)
                markers{k} = DraggablePoint2D(UIAxes,nvec(k),0,callbackFunc,2);
                set(markers{k}.lineHandle,... 
                     'markerfacecolor',color(1,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(UIAxes,'off')

            % Axis limits/modes
            xlim(UIAxes,[0,nvec(end)])
            xticks(UIAxes,nvec)
            xlabel(UIAxes,xLbl,'interpreter','latex')
            ylabel(UIAxes,yLbl,'interpreter','latex')
            disableDefaultInteractivity(UIAxes)
            axtoolbar(UIAxes,{});

        end

        function plotHandle = setUpRealAxes(app,UIAxes,xLbl,yLbl)

            cla(UIAxes)

            plotHandle  = plot(UIAxes,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.timeColors(1,:));

            % Adjust the graph appearance
            xlim(UIAxes,[app.tvec(1),app.tvec(end)])
            ylim(UIAxes,app.reLims)
            xticks(UIAxes,[app.tvec(1), mean(app.tvec),app.tvec(end)])
            yticks(UIAxes,[app.reLims(1), mean(app.reLims),app.reLims(2)])
            xlabel(UIAxes,xLbl,'interpreter','latex')
            ylabel(UIAxes,yLbl,'interpreter','latex')
            disableDefaultInteractivity(UIAxes)
            axtoolbar(UIAxes,{});

        end

        function setupEquationAxes(app,UIAxes,eq)
            % Adjust the equation axes
            axis(UIAxes,"off")
            disableDefaultInteractivity(UIAxes)
            axtoolbar(UIAxes,{});
            cla(UIAxes)
            text(UIAxes,0.45,0.5,eq,"color",[0,0,0]*0.95,"Interpreter","latex",...
                "HorizontalAlignment","center",'FontSize',14)
        end

        % Executes when a Fourier coefficient marker is moved
        function coef1Moved(app,xp,yp)

            % Pull the coefficients
            an = app.pullMarkerPos(app.markers_an);
            bn = app.pullMarkerPos(app.markers_bn);
            
            % Compute the function values
            f = app.evalFourierSinCos(an,bn,app.fbcos,app.fbsin);

            % Update the stem plots
            app.plot_an.YData = an;
            app.plot_bn.YData = bn;

            app.realPlot1.YData = f;
        end

        % Pull the coefficients
        function coef = pullMarkerPos(app,markers)
            coef = zeros(1,numel(markers));
            for k = 1:numel(markers)
                [~,coef(k)] = markers{k}.getPos();
            end
        end


        %% Math functions
        % Compute the fourier series sine/cosine basis
        function [fbcos, fbsin, tvec, nvec] = sinCosBasis(app,Nfreq,Nt,P)
            % Nf is the number of frequencies
            % Nt is the number of time points
            % P is the period (also the final time)

            % Time domain
            tvec = linspace(0,P,Nt)';            

            % P is the period
            nvec = 0:Nfreq;
            freq = 1/P*nvec;

            % Basis functions
            fbcos = cos(2*pi*freq.*tvec);
            fbsin = sin(2*pi*freq.*tvec);
        end

         % Evaluate Fourier series
         function [yFourier] = evalFourierSinCos(app,a,b,fbcos,fbsin)
            % Compute the fourier series
            yFourier = sum(a.*fbcos + b.*fbsin,2);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setUpSinCos
        end

        % Value changed function: NSpinnerSineCosine
        function NSpinnerSineCosineValueChanged(app, event)
            app.setUpSinCos
        end

        % Button pushed function: ClearButtonSineCosine
        function ClearButtonSineCosinePushed(app, event)
            app.setUpSinCos
        end

        % Callback function
        function AnimateButtonPushed(app, event)
            app.animatePlot
        end

        % Callback function
        function StopAnimationButtonPushed(app, event)
            app.stopAnimation = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhaseandMagnitudeUIFigure = uifigure('Visible', 'off');
            app.PhaseandMagnitudeUIFigure.Name = 'Phase and Magnitude';
            app.GridLayout = uigridlayout(app.PhaseandMagnitudeUIFigure);
            app.FrequencyDomainaandbPanel = uipanel(app.GridLayout);
            app.FrequencyDomainaandbPanel.Title = 'Frequency Domain: aₙ and bₙ';
            app.GridLayout3 = uigridlayout(app.FrequencyDomainaandbPanel);
            app.UIAxes_bn = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_bn, 't')
            ylabel(app.UIAxes_bn, 'y')
            zlabel(app.UIAxes_bn, 'Z')
            app.UIAxes_bn.XLim = [0 8];
            app.UIAxes_bn.YLim = [-1 1];
            app.UIAxes_an = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_an, 't')
            ylabel(app.UIAxes_an, 'y')
            zlabel(app.UIAxes_an, 'Z')
            app.UIAxes_an.XLim = [0 8];
            app.UIAxes_an.YLim = [-1 1];
            app.NLabel = uilabel(app.GridLayout3);
            app.NLabel.Text = 'N:';
            app.NSpinnerSineCosine = uispinner(app.GridLayout3);
            app.NSpinnerSineCosine.Limits = [1 20];
            app.NSpinnerSineCosine.ValueChangedFcn = createCallbackFcn(app, @NSpinnerSineCosineValueChanged, true);
            app.NSpinnerSineCosine.Value = 6;
            app.ClearButtonSineCosine = uibutton(app.GridLayout3, 'push');
            app.ClearButtonSineCosine.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonSineCosinePushed, true);
            app.ClearButtonSineCosine.Text = 'Clear';
            app.FrequencyDomainAandPanel = uipanel(app.GridLayout);
            app.FrequencyDomainAandPanel.Title = 'Frequency Domain: Aₙ and φₙ';
            app.GridLayout6 = uigridlayout(app.FrequencyDomainAandPanel);
            app.UIAxes_An = uiaxes(app.GridLayout6);
            xlabel(app.UIAxes_An, 't')
            ylabel(app.UIAxes_An, 'y')
            zlabel(app.UIAxes_An, 'Z')
            app.UIAxes_An.XLim = [0 8];
            app.UIAxes_An.YLim = [-1 1];
            app.UIAxes_phin = uiaxes(app.GridLayout6);
            xlabel(app.UIAxes_phin, 't')
            ylabel(app.UIAxes_phin, 'y')
            zlabel(app.UIAxes_phin, 'Z')
            app.UIAxes_phin.XLim = [0 8];
            app.UIAxes_phin.YLim = [-1 1];
            app.ClearButtonMagPhase = uibutton(app.GridLayout6, 'push');
            app.ClearButtonMagPhase.Text = 'Clear';
            app.NSpinner_2Label = uilabel(app.GridLayout6);
            app.NSpinner_2Label.Text = 'N:';
            app.NSpinnerMagPhase = uispinner(app.GridLayout6);
            app.NSpinnerMagPhase.Limits = [1 20];
            app.NSpinnerMagPhase.Value = 6;
            app.TimeDomainSineandCosineSeriesPanel = uipanel(app.GridLayout);
            app.TimeDomainSineandCosineSeriesPanel.Title = 'Time Domain: Sine and Cosine Series';
            app.GridLayout4 = uigridlayout(app.TimeDomainSineandCosineSeriesPanel);
            app.UIAxesSineCosine = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesSineCosine, 'X')
            ylabel(app.UIAxesSineCosine, 'Y')
            zlabel(app.UIAxesSineCosine, 'Z')
            app.EqAxesSineCosine = uiaxes(app.GridLayout4);
            app.EqAxesSineCosine.Visible = 'off';
            app.DefinitionPanelSineCosine = uipanel(app.GridLayout4);
            app.DefinitionPanelSineCosine.Title = 'Definition';
            app.TimeDomainPhaseandMagnitudeSeriesPanel = uipanel(app.GridLayout);
            app.TimeDomainPhaseandMagnitudeSeriesPanel.Title = 'Time Domain: Phase and Magnitude Series';
            app.GridLayout5 = uigridlayout(app.TimeDomainPhaseandMagnitudeSeriesPanel);
            app.UIAxesMagPhase = uiaxes(app.GridLayout5);
            xlabel(app.UIAxesMagPhase, 'X')
            ylabel(app.UIAxesMagPhase, 'Y')
            zlabel(app.UIAxesMagPhase, 'Z')
            app.EqAxesMagPhase = uiaxes(app.GridLayout5);
            app.EqAxesMagPhase.Visible = 'off';
            app.DefinitionPanelMagPhase = uipanel(app.GridLayout5);
            app.DefinitionPanelMagPhase.Title = 'Definition';
            % Show the figure after all components are created
            app.PhaseandMagnitudeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseMagnitude

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhaseandMagnitudeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhaseandMagnitudeUIFigure)
        end
    end
end