classdef SinCosSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SineandCosineSeriesUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ControlsPanel                matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        TriangleButton               matlab.ui.control.Button
        SquareButton                 matlab.ui.control.Button
        CyclesSpinner                matlab.ui.control.Spinner
        CyclesSpinnerLabel           matlab.ui.control.Label
        BasisButtonGroup             matlab.ui.container.ButtonGroup
        SineButton                   matlab.ui.control.ToggleButton
        CosineButton                 matlab.ui.control.ToggleButton
        SawtoothButton               matlab.ui.control.Button
        PresetsLabel                 matlab.ui.control.Label
        AnimateButton                matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        NmodesSpinner                matlab.ui.control.Spinner
        NmodesSpinnerLabel           matlab.ui.control.Label
        EqAxes                       matlab.ui.control.UIAxes
        PlotsPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        TimeAxes                     matlab.ui.control.UIAxes
        CoefAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Nmodes = 5 % Default
        NmaxModes = 10 % Maximum number of modes
        colors = lines(20); % The colors used for different lines
        Nt = 250; % Time plot points
        lw = 1.5; % Series line width
        ms = 8; % Marker size for coefs
        animSteps = 25; % Steps in the animation
        tlimBasis = [0,1];
        eqTextColor = [0 0 0];
        funcColor = [0.2 0.2 0.2];
        fadeColor = [1 1 1];
        cWeight = 0.55; % Weight for the color when it is incorporated
       
        % coefficients/variables
        aCoef
        t
        tlim 

        % plot objects/containers
        coefStems
        coefMarkers
        seriesPlots
        sumPlot
    end
    
    methods (Access = private)
        
        function NModesChanged(app,NmodesValue)
            % Runs when the app starts, the clear button is pressed, or the
            % number of modes change. This basically resets the app.

            app.setTLim
            app.Nmodes = NmodesValue;
            % Set the coefficients
            if(numel(app.aCoef) >= app.Nmodes)
                app.aCoef = app.aCoef(1:app.Nmodes);
            else
                a = app.aCoef;
                app.aCoef = zeros(app.Nmodes,1);
                app.aCoef(1:length(a)) = a;
            end

            % Create the equation text
            funcStr = "cos";
            if(app.SineButton.Value)
                funcStr = "sin";
            end
            eq = "$$f(t) = \sum_{n = 1}^{" + num2str(app.Nmodes) + "} a_n \"+ funcStr + "( 2 \pi n t )$$";
            cla(app.EqAxes)
            text(app.EqAxes,-0.05,0.5,eq,"color",app.eqTextColor,"Interpreter","latex",...
                "HorizontalAlignment","left",'FontSize',16)

            % AXES 1: Fourier coefficients
            cla(app.CoefAxes)
            app.coefMarkers = {};
            hold(app.CoefAxes,'on')
            % Add the line plot
            for k = 1:app.Nmodes
                app.coefStems{k} = plot(app.CoefAxes,0,0,...
                    'linewidth',1,'color',app.colors(k,:),'marker','none');

                app.coefMarkers{k} = DraggablePoint2D(app.CoefAxes,...
                                       app.aCoef(k),app.Nmodes - k+1,@app.coefMarkerMoved,1);
                set(app.coefMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(k,:),...
                     'color',[0.8,0.8,0.8], ...
                     'markersize',app.ms);   
            end
            hold(app.CoefAxes,'off')
            axis(app.CoefAxes,[-1,1,0.5,app.Nmodes+0.5+app.Nmodes])
            yticks(app.CoefAxes,1:app.Nmodes)
            xticks(app.CoefAxes,[-1 1])
            ytl = string(app.Nmodes:-1:1) + "  ";
            yticklabels(app.CoefAxes,ytl)


            % AXES 2: Time series
            cla(app.TimeAxes)
            app.seriesPlots = {};
            hold(app.TimeAxes,'on')
            for k = 1:app.Nmodes
                app.seriesPlots{k} = plot(app.TimeAxes,app.t,app.t*0,...
                    'linewidth',app.lw,'color',app.colors(k,:),'marker','none');
            end
            app.sumPlot = plot(app.TimeAxes,app.t,app.t*0,...
                'linewidth',app.lw,'color',app.funcColor,'marker','none');
            hold(app.TimeAxes,'off')
            axis(app.TimeAxes,[app.tlim,0.5,app.Nmodes+0.5+app.Nmodes])
            yticks(app.TimeAxes,[])
            xticks(app.TimeAxes,app.tlim)

            % Update
            coefMarkerMoved(app,0,0);
        end

        
        function pullCoef(app)
            % Pull coef data
            for k = 1:numel(app.coefMarkers)
                [xp,yp] = app.coefMarkers{k}.getPos();
                app.aCoef(k) = xp;
            end
        end


        function coefMarkerMoved(app,xp,yp)
            % Modify the plots after an adjustment to the coefs
            app.pullCoef

            % Adjust the stem plot
            for k = 1:app.Nmodes
                app.coefStems{k}.XData = [0;app.aCoef(k)];
                app.coefStems{k}.YData = app.Nmodes - [k;k]+1;
            end

            % Adjust the time series
            for k = 1:app.Nmodes
                app.seriesPlots{k}.YData = 0.45*app.computeMode(k) + app.Nmodes - k+1;
            end

            app.sumPlot.YData = 0.45*app.computeFourierSum + app.Nmodes*1.5 + 0.5;

        end

        function fn = computeMode(app,n)
            % Computes the basis
            if(strcmpi(app.BasisButtonGroup.SelectedObject.Text,'Cosine'))
                fn = app.aCoef(n)*cos(2*pi*n*app.t);
            else
                fn = app.aCoef(n)*sin(2*pi*n*app.t);
            end
        end

        function f = computeFourierSum(app)
            % Sums over the modes
            f = 0*app.t;
            for k = 1:app.Nmodes
                f = f + app.computeMode(k);
            end
        end

        function setTLim(app)
            % Sets the time axis limits based on the cycles setting
            app.tlim = app.tlimBasis*app.CyclesSpinner.Value;
            app.t = linspace(app.tlim(1),app.tlim(2),app.Nt);
        end

        function animatePlot(app)
            % Animates the plot
            
            app.AnimateButton.Enable = false;
            % Animation color/variable
            c = app.funcColor;
            cPrev = c;
            uvec = linspace(0,1,app.animSteps);

            % Set to zero
            app.sumPlot.YData = 0.45*app.t*0 + app.Nmodes*1.5 + 0.5;

            % Plot to zero
            for u = uvec
                app.sumPlot.YData = (1-u)*0.45*app.computeFourierSum + app.Nmodes*1.5 + 0.5;
                pause(0)
            end

            % Main animation loop
            for k = 1:app.Nmodes
                if(app.aCoef(k)~=0)
                    % move up
                    for u = uvec
                        y1 = app.Nmodes - k+1 ;
                        y2 = app.Nmodes*1.5 + 0.5;
                        app.seriesPlots{k}.YData = 0.45*app.computeMode(k) ...
                            + y1 + u*(y2-y1);
                        pause(0);
                    end

                    % Transfer mode 
                    f = app.t*0;
                    for j = 1:(k-1)
                        f = f+0.45*app.computeMode(j);
                    end

                    for u = uvec
                        % Adjust the Y-values as the function transitions
                        app.sumPlot.YData = 0.45*u*app.computeMode(k) + f + app.Nmodes*1.5 + 0.5;
                        app.seriesPlots{k}.YData = 0.45*(1-u)*app.computeMode(k) + app.Nmodes*1.5 + 0.5;
                        % Transition color for the main function
                        if(u < 0.5)
                            c = (1-2*u)*cPrev + 2*u*app.funcColor;
                        else
                            c = (1-(2*u-1))*app.funcColor + (2*u-1)*(app.cWeight*app.colors(k,:)+ (1-app.cWeight)*app.funcColor);
                        end
                        app.sumPlot.Color = c;
                        app.seriesPlots{k}.Color = (u)*app.fadeColor + (1-u)*app.colors(k,:);

                        pause(0)
                    end
                    cPrev = c;
                end

                app.seriesPlots{k}.Color = app.fadeColor;
            end

            % Return plot to original format smoothly
            app.coefMarkerMoved(0,0);
            for u = uvec
                cu = c*(1-u) + u*app.funcColor;
                app.sumPlot.Color = cu;
                for k = 1:app.Nmodes
                    app.seriesPlots{k}.Color = (1-u)*app.fadeColor + u*app.colors(k,:);
                end
                pause(0)
            end

            drawnow
            app.NModesChanged(app.Nmodes)
            app.AnimateButton.Enable = true;

        end

        
        function sawtooth(app)
            % Set default coefs: sawtooth

            n = 1:app.Nmodes;
            app.aCoef = -2./(pi*n).*(-1).^n;
            app.aCoef = app.aCoef./max(abs(app.aCoef));
            app.BasisButtonGroup.SelectedObject = app.SineButton;
            app.NModesChanged(app.Nmodes)
            app.coefMarkerMoved
            
        end

        function squareWave(app)
            % Set default coefs: square wave

            n = 1:app.Nmodes;
            app.aCoef = 0*n;
            n2 = (1:2:app.Nmodes);
            app.aCoef(1:2:app.Nmodes) = 1./(n2*pi);
            
            app.aCoef = app.aCoef./max(abs(app.aCoef));
            app.BasisButtonGroup.SelectedObject = app.SineButton;
            app.NModesChanged(app.Nmodes)
            app.coefMarkerMoved
        end

        function triangleWave(app)
            % Set default coefs: triangle wave

            n = 1:app.Nmodes;
            app.aCoef = 0*n;
            n2 = (1:2:app.Nmodes);
            app.aCoef(1:2:app.Nmodes) = 8*(-1).^((n2-1)/2)./(pi^2*n2.^2);
            
            app.aCoef = app.aCoef./max(abs(app.aCoef));
            app.BasisButtonGroup.SelectedObject = app.SineButton;
            app.NModesChanged(app.Nmodes)
            app.coefMarkerMoved
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       
            % Adjust the equation axes
            axis(app.EqAxes,"off")
            disableDefaultInteractivity(app.EqAxes)
            axtoolbar(app.EqAxes,{});

            disableDefaultInteractivity(app.CoefAxes)
            axtoolbar(app.CoefAxes,{});

            disableDefaultInteractivity(app.TimeAxes)
            axtoolbar(app.TimeAxes,{});

            % Set the inital modes
            app.NmodesSpinner.Value = app.Nmodes;
            app.NmodesSpinner.Limits = [1,app.NmaxModes];

            app.aCoef = zeros(app.Nmodes);

            % Set up the plots, coef markers, etc.
            app.NModesChanged(app.NmodesSpinner.Value)
            

        end

        % Value changed function: NmodesSpinner
        function NmodesSpinnerValueChanged(app, event)
            value = app.NmodesSpinner.Value;
            app.NModesChanged(value)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            app.aCoef = app.aCoef*0;
            NModesChanged(app,app.Nmodes)
            app.AnimateButton.Enable = true;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            app.animatePlot
        end

        % Selection changed function: BasisButtonGroup
        function BasisButtonGroupSelectionChanged(app, event)
            % selectedButton = app.BasisButtonGroup.SelectedObject;
            app.NModesChanged(app.Nmodes)
        end

        % Button pushed function: SawtoothButton
        function SawtoothButtonPushed(app, event)
            app.sawtooth
        end

        % Value changed function: CyclesSpinner
        function CyclesSpinnerValueChanged(app, event)
            % value = app.CyclesSpinner.Value;
            app.NModesChanged(app.Nmodes)
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
            app.squareWave
        end

        % Button pushed function: TriangleButton
        function TriangleButtonPushed(app, event)
            app.triangleWave
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SineandCosineSeriesUIFigure = uifigure('Visible', 'off');
            app.SineandCosineSeriesUIFigure.Name = 'Sine and Cosine Series';
            app.GridLayout = uigridlayout(app.SineandCosineSeriesUIFigure);
            app.PlotsPanel = uipanel(app.GridLayout);
            app.PlotsPanel.Title = 'Plots';
            app.GridLayout2 = uigridlayout(app.PlotsPanel);
            app.CoefAxes = uiaxes(app.GridLayout2);
            xlabel(app.CoefAxes, 'a_n')
            ylabel(app.CoefAxes, 'n')
            zlabel(app.CoefAxes, 'Z')
            app.CoefAxes.XLim = [0 1];
            app.CoefAxes.YLim = [0 1];
            app.CoefAxes.ALim = [0 1];
            app.CoefAxes.YAxisLocation = 'origin';
            app.TimeAxes = uiaxes(app.GridLayout2);
            xlabel(app.TimeAxes, 't')
            ylabel(app.TimeAxes, 'f_n')
            zlabel(app.TimeAxes, 'Z')
            app.TimeAxes.XLim = [0 1];
            app.TimeAxes.YLim = [0 1];
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout3 = uigridlayout(app.ControlsPanel);
            app.EqAxes = uiaxes(app.GridLayout3);
            app.NmodesSpinnerLabel = uilabel(app.GridLayout3);
            app.NmodesSpinnerLabel.Text = 'N modes';
            app.NmodesSpinner = uispinner(app.GridLayout3);
            app.NmodesSpinner.Limits = [1 10];
            app.NmodesSpinner.ValueChangedFcn = createCallbackFcn(app, @NmodesSpinnerValueChanged, true);
            app.NmodesSpinner.Value = 5;
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AnimateButton = uibutton(app.GridLayout3, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.PresetsLabel = uilabel(app.GridLayout3);
            app.PresetsLabel.Text = 'Presets';
            app.SawtoothButton = uibutton(app.GridLayout3, 'push');
            app.SawtoothButton.ButtonPushedFcn = createCallbackFcn(app, @SawtoothButtonPushed, true);
            app.SawtoothButton.Text = 'Sawtooth';
            app.BasisButtonGroup = uibuttongroup(app.GridLayout3);
            app.BasisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BasisButtonGroupSelectionChanged, true);
            app.BasisButtonGroup.Title = 'Basis';
            app.CosineButton = uitogglebutton(app.BasisButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.CosineButton.Value = true;
            app.SineButton = uitogglebutton(app.BasisButtonGroup);
            app.SineButton.Text = 'Sine';
            app.CyclesSpinnerLabel = uilabel(app.GridLayout3);
            app.CyclesSpinnerLabel.Text = 'Cycles';
            app.CyclesSpinner = uispinner(app.GridLayout3);
            app.CyclesSpinner.Limits = [1 3];
            app.CyclesSpinner.ValueChangedFcn = createCallbackFcn(app, @CyclesSpinnerValueChanged, true);
            app.CyclesSpinner.Value = 2;
            app.SquareButton = uibutton(app.GridLayout3, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.TriangleButton = uibutton(app.GridLayout3, 'push');
            app.TriangleButton.ButtonPushedFcn = createCallbackFcn(app, @TriangleButtonPushed, true);
            app.TriangleButton.Text = 'Triangle';
            % Show the figure after all components are created
            app.SineandCosineSeriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SinCosSeries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SineandCosineSeriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SineandCosineSeriesUIFigure)
        end
    end
end