classdef ClickAndDragFourier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes1     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        colors % The colors used for different lines
        Ns = 50; % Number of points in the spline

        plotMarkers % The moveable plot markers
        splineLine % Spline plot (adjusts as the plot markers are moved)
        spectrumLine % Spectrum plot (adjusts as the plot markers are moved)

    end
    
    methods (Access = private)
        %% Functions that interact with many parts of the code
        % Executes when a marker is moved
        function markerMoved(app,xp,yp)
            [x,y] = pullData(app);
            [xs,ys] = genSpline(app,x,y);
            updatePlot(app,xs,ys);
            updateSpectrum(app,xs,ys)
        end

        %% These functions create the line plot
        
        % Pulls the data from the plot markers 
        function [x,y] = pullData(app)
            x = zeros(numel(app.plotMarkers),1);
            y = x;
            for k = 1:numel(app.plotMarkers)
                [xp,yp] = app.plotMarkers{k}.getPos();
                x(k) = xp;
                y(k) = yp;
            end

            [x,idx] = unique(x);
            y = y(idx);
        end

        % Creates the spline
        function [xs,ys] = genSpline(app,x,y)
            xs = linspace(min(x),max(x),app.Ns);
            ys = spline(x,y,xs);
        end
        
        % Updates the plot
        function updatePlot(app,xs,ys)
            app.splineLine.XData = xs;
            app.splineLine.YData = ys;
        
        end


        %% These function compute the FFT

        % Compute the one-sided spectrum
        function [f,P1] = computeSpectrum(app,t,y)
            N = length(t);
            T = (t(end)-t(1))/length(t);
            Fs = 1/T;
            F = fft(y);
            P2 = (abs(F/N));
            P1 = P2(1:round(N/2+1));
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(N/2))/N;
        end

        function updateSpectrum(app,xs,ys)
            [f,P1] = computeSpectrum(app,xs,ys);
            app.spectrumLine.XData = f;
            app.spectrumLine.YData = P1;
        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Change graph appearance
            ylim(app.UIAxes1,[-1,1])
            ylim(app.UIAxes2,[-0.05,1])
            xlabel(app.UIAxes1,'$t (s)$','interpreter','latex')
            ylabel(app.UIAxes1,'$y$','interpreter','latex')
            xlabel(app.UIAxes2,'$\omega (Hz)$','interpreter','latex')
            ylabel(app.UIAxes2,'$|\hat{y}|$','interpreter','latex')
            

            % Create the spline and spectrum lines
            app.colors = lines(4);
            app.splineLine = plot(app.UIAxes1,0,0,'linewidth',1,'color',app.colors(1,:));
            app.spectrumLine = stem(app.UIAxes2,0,0,'linewidth',1,'color',app.colors(3,:));

            % Create plot markers
            x = linspace(0,1,10);
            y = sin(x*2*pi);
            
            hold(app.UIAxes1,'on')
            for k = 1:numel(x)
                app.plotMarkers{k} = DraggablePoint2D(app.UIAxes1,...
                                        x(k),y(k),@app.markerMoved,2);
                set(app.plotMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(1,:),...
                     'color',[0.8,0.8,0.8]);
                
            end
            hold(app.UIAxes1,'off')
            
            % Update the spline plot
            app.markerMoved;

            % Update the spectrum axes
            xlim(app.UIAxes2,minmax(app.spectrumLine.XData))


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 't')
            ylabel(app.UIAxes1, 'y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XLim = [0 1];
            app.UIAxes1.YLim = [0 1];
            app.UIAxes1.ZLim = [0 1];
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClickAndDragFourier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end