classdef ComplexFourierSeries < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComplexFourierSeriesUIFigure  matlab.ui.Figure
        GridLayout4                   matlab.ui.container.GridLayout
        ControlsPanel                 matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        StopAnimationButton           matlab.ui.control.Button
        NSpinner                      matlab.ui.control.Spinner
        NLabel                        matlab.ui.control.Label
        AnimateButton                 matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        GridLayout                    matlab.ui.container.GridLayout
        TimeDomainPanel               matlab.ui.container.Panel
        UIAxesAnimate                 matlab.ui.control.UIAxes
        UIAxesImag                    matlab.ui.control.UIAxes
        UIAxesReal                    matlab.ui.control.UIAxes
        UIAxesComplex                 matlab.ui.control.UIAxes
        FrequencyDomainComplexFourierSeriesCoefficientsPanel  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxesImagCn                  matlab.ui.control.UIAxes
        UIAxesRealCn                  matlab.ui.control.UIAxes
        FourierSeriesDefinitionPanel  matlab.ui.container.Panel
        EqAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
               
        P = 1; % Period (determines the axis limits)
        Nmodes % Number of modes (set by spinner)
        Nt = 250; % Number of temporal points

        imLims = 2*[-1,1] % Im limits on complex plane
        reLims = 2*[-1,1] % Re limits on complex plane       

        lwSeries = 1.25; % Linewidth of time series
        lwAnimate = 1; % Line width of the animation

        % Plot objects
        realMarkers % Real coef markers
        imagMarkers % Imag coef markers

        spectrumLinesRe % stem plot for spectrum coeffs (re)
        spectrumLinesIm % stem plot for spectrum coeffs (im)
        
        realPlot      % Line plot for real time series
        complexPlot   % Line plot for complex time series
        imagPlot      % Line plot from imaginary time series
        
        colors % The colors used for different lines
        stopAnimation % Flag to stop the animation

        % Math objects
        nvec % Vector of mode indices
        expBasis % Complex exponential basis
        tvec % time array 
        fvec % function values

        %#ok<*INUSL> 
        %#ok<*INUSD> 
    end
    
    methods (Access = private)
        %% These functions set up the plots, etc.

        function setUp(app)
                     
            app.colors = lines(4);

            % Get the initial number of modes
            app.Nmodes = app.NSpinner.Value;

            % Set up the complex Fourier basis
            [app.expBasis, app.tvec, app.nvec] = cfBasis(app,app.Nmodes,app.Nt,app.P);

            % Create the markers
            app.setUpFreqAxes
            app.setUpTimeDomainAxes

            % Create equation
            app.createEquationAxes

            % Update function
            app.coefMoved
        end

        function setUpFreqAxes(app)

            % Clear the axes
            cla(app.UIAxesRealCn)
            cla(app.UIAxesImagCn)
            app.realMarkers = {};
            app.imagMarkers = {};

            % AXIS 1: real coefficients
            hold(app.UIAxesRealCn,'on')
            % Create stem plot
            app.spectrumLinesRe = stem(app.UIAxesRealCn,app.nvec,app.nvec*0,...
                'linewidth',1,'color',app.colors(3,:),'marker','none');
            % Add markers
            for k = 1:numel(app.nvec)
                app.realMarkers{k} = DraggablePoint2D(app.UIAxesRealCn,...
                                        app.nvec(k),0,@app.coefMoved,2);
                set(app.realMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(3,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(app.UIAxesRealCn,'off')

            % AXIS 2: sine coefficients (b)
            hold(app.UIAxesImagCn,'on')
            % Create stem plot
            app.spectrumLinesIm = stem(app.UIAxesImagCn,app.nvec,app.nvec*0,...
                'linewidth',1,'color',app.colors(3,:),'marker','none');
            % Add markers
            for k = 1:numel(app.nvec)
                app.imagMarkers{k} = DraggablePoint2D(app.UIAxesImagCn,...
                                        app.nvec(k),0,@app.coefMoved,2);
                set(app.imagMarkers{k}.lineHandle,... 
                     'markerfacecolor',app.colors(3,:),...
                     'color',[0.8,0.8,0.8]);   
            end
            hold(app.UIAxesImagCn,'off')

            % Change graph appearance: Real coefficients
            xlim(app.UIAxesRealCn,app.nvec([1,end]))
            xticks(app.UIAxesRealCn,app.nvec)
            xlabel(app.UIAxesRealCn,'$n$','interpreter','latex')
            ylabel(app.UIAxesRealCn,'Re$(c_n)$','interpreter','latex')
            disableDefaultInteractivity(app.UIAxesRealCn)
            axtoolbar(app.UIAxesRealCn,{});

            % Change graph appearance: Imaginary coefficients
            xlim(app.UIAxesImagCn,app.nvec([1,end]))
            xticks(app.UIAxesImagCn,app.nvec)
            xlabel(app.UIAxesImagCn,'$n$','interpreter','latex')
            ylabel(app.UIAxesImagCn,'Im$(c_n)$','interpreter','latex')
            disableDefaultInteractivity(app.UIAxesImagCn)
            axtoolbar(app.UIAxesImagCn,{});

        end

        function setUpTimeDomainAxes(app)
                        
            % Clear the axes
            cla(app.UIAxesComplex)
            cla(app.UIAxesReal)
            cla(app.UIAxesImag)

            % Real time series line
            app.realPlot = plot(app.UIAxesReal,app.tvec*0,app.tvec,...
                'linewidth',app.lwSeries,'color',app.colors(1,:));
            app.imagPlot = plot(app.UIAxesImag,app.tvec,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.colors(2,:));     
            app.complexPlot = plot(app.UIAxesComplex,app.tvec*0,app.tvec*0,...
                'linewidth',app.lwSeries,'color',app.colors(4,:));     

            % Change graph appearance: complex plane
            xlim(app.UIAxesComplex,app.reLims)
            ylim(app.UIAxesComplex,app.imLims)
            xticks(app.UIAxesComplex,[app.reLims(1), mean(app.reLims),app.reLims(2)])
            yticks(app.UIAxesComplex,[app.imLims(1), mean(app.imLims),app.imLims(2)])
            xlabel(app.UIAxesComplex,'Re$(f)$','interpreter','latex')
            ylabel(app.UIAxesComplex,'Im$(f)$','interpreter','latex')
            disableDefaultInteractivity(app.UIAxesComplex)
            axtoolbar(app.UIAxesComplex,{});

            % Graph appearance: real 
            xlim(app.UIAxesReal,app.reLims)
            ylim(app.UIAxesReal,[app.tvec(1),app.tvec(end)])
            xticks(app.UIAxesReal,[app.reLims(1), mean(app.reLims),app.reLims(2)])
            yticks(app.UIAxesReal,[app.tvec(1), mean(app.tvec),app.tvec(end)])
            xlabel(app.UIAxesReal,"")
            ylabel(app.UIAxesReal,'$t$','interpreter','latex')
            title(app.UIAxesReal,"Real time series")
            disableDefaultInteractivity(app.UIAxesReal)
            axtoolbar(app.UIAxesReal,{});

            % Graph appearance: imaginary
            ylim(app.UIAxesImag,app.imLims)
            xlim(app.UIAxesImag,[app.tvec(1),app.tvec(end)])
            yticks(app.UIAxesImag,[app.imLims(1), mean(app.imLims),app.imLims(2)])
            xticks(app.UIAxesImag,[app.tvec(1), mean(app.tvec),app.tvec(end)])
            xlabel(app.UIAxesImag,'$t$','interpreter','latex')
            ylabel(app.UIAxesImag,"")
            title(app.UIAxesImag,"Imaginary time series")
            disableDefaultInteractivity(app.UIAxesImag)
            axtoolbar(app.UIAxesImag,{});

            % Animation axes
            axis(app.UIAxesAnimate,"off")
        end


        % Executes when a Fourier coefficient marker is moved
        function coefMoved(app,xp,yp)
            [cnRe,cnIm] = pullFourierMarkers(app);
            f = evalFourier(app,cnRe,cnIm);

            updateTimeSeries(app,f)
            updateSpectrum(app,cnRe,cnIm)

        end

        % Pull the coefficients
        function [cnRe,cnIm] = pullFourierMarkers(app)
            cnRe = zeros(1,numel(app.realMarkers));
            cnIm = zeros(1,numel(app.imagMarkers));
                for k = 1:numel(app.realMarkers)
                    [~,cnRe(k)] = app.realMarkers{k}.getPos();
                    [~,cnIm(k)] = app.imagMarkers{k}.getPos();
                end
        end

        % Updates the time series plots
        function updateTimeSeries(app,f)
            
            % Update the app storage
            app.fvec = f;

            % Complex plot
            app.complexPlot.XData = real(f);
            app.complexPlot.YData = imag(f);
            % Real plot
            app.realPlot.XData = real(f);
            % Imaginary plot
            app.imagPlot.YData = imag(f);
        end

        % Updates the spectrum plots
        function updateSpectrum(app,a,b)
            app.spectrumLinesRe.YData = a;
            app.spectrumLinesIm.YData = b;
        end
        function updateSpectrumMarkers(app,a,b)
            for k = 1:numel(app.fsinMarkers)
                app.realMarkers{k}.lineHandle.YData = a(k);
                app.imagMarkers{k}.lineHandle.YData = b(k);
            end
        end


        %% Animation functions


        function animatePlot(app)

            app.StopAnimationButton.Visible = true;
            app.stopAnimation = false;
            app.UIAxesAnimate.Visible = "on";
            axis(app.UIAxesAnimate,"off")
            for k = 1:numel(app.tvec)

                t = app.tvec(k);
                f = app.fvec(k);

                pComplex = [real(f),imag(f)];
                pReal = [real(f),t];
                
                p1Fig = app.figPos(app.UIAxesComplex,pComplex);
                p2Fig = app.figPos(app.UIAxesReal,pReal);

                p1anim = app.axisPos(app.UIAxesAnimate,p1Fig);
                p2anim = app.axisPos(app.UIAxesAnimate,p2Fig);

                xvert = [p1anim(1),p2anim(1)];
                yvert = [p1anim(2),p2anim(2)];

                plot(app.UIAxesAnimate,xvert,yvert,'-','linewidth',app.lwAnimate)

                if(mod(k,20)==0)
                    drawnow
                    if(app.stopAnimation)
                        break
                    end
                end
                pause(0)
            end


            % Clear the animation axes
            drawnow
            cla(app.UIAxesAnimate)
            app.UIAxesAnimate.Visible = "off";
            app.StopAnimationButton.Visible = false;
        end


        function pFig = figPos(app,ax,p)
            axUnits = ax.Units;
            ax.Units = 'normalized';
            pos = ax.InnerPosition;

            % Shifted so that the origin is at the lower-left corner
            x = p(1)-ax.XLim(1);
            y = p(2)-ax.YLim(1);
            
            % Compute the position of the point in the figure
            xFig = x*(pos(3))/(ax.XLim(2) - ax.XLim(1)) + pos(1);
            yFig = y*(pos(4))/(ax.YLim(2) - ax.YLim(1)) + pos(2);
            
            pFig = [xFig,yFig];
            ax.Units = axUnits;
        end

        function pAxis = axisPos(app,ax,pFig)
            axUnits = ax.Units;
            ax.Units = 'normalized';
            pos = ax.InnerPosition;

            x = (pFig(1) - pos(1))*(ax.XLim(2) - ax.XLim(1))/pos(3);
            y = (pFig(2) - pos(2))*(ax.YLim(2) - ax.YLim(1))/pos(4);

            pAxis = [x+ax.XLim(1), y+ax.YLim(1)];

            ax.Units = axUnits;
        end


        %% Math functions
        % Creates the spline
        function [xs,ys] = genSpline(app,x,y)
            xs = linspace(min(x),max(x),app.Ns);
            ys = spline(x,y,xs);
        end
        
        % Compute the complex Fourier series basis
        function [expBasis, tvec, nv] = cfBasis(app,Nfreq,Nt,P) 
            % Nf is the number of frequencies
            % Nt is the number of time points
            % P is the period (also the final time)

            % Time domain
            tvec = linspace(0,P,Nt)';
            % Frequency indices
            nv = -Nfreq:Nfreq;
            % Exponential basis
            expBasis = exp(2*pi*1i*nv.*tvec);

        end

        % Evaluate Fourier series
        function f = evalFourier(app,cnRe,cnIm)
            cn = cnRe + cnIm*1i;
            % Compute the fourier series
            f = sum(cn.*app.expBasis,2);
        end

                
        %% Controls functions

        function createEquationAxes(app)
            % Adjust the equation axes
            axis(app.EqAxes,"off")
            disableDefaultInteractivity(app.EqAxes)
            axtoolbar(app.EqAxes,{});
            eq = "$$f(t) = \sum_{-N}^N c_n e^{ 2\pi i n t}$$";
            cla(app.EqAxes)
            text(app.EqAxes,0.45,0.5,eq,"color",[1,1,1]*0.95,"Interpreter","latex",...
                "HorizontalAlignment","center",'FontSize',14)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setUp
        end

        % Value changed function: NSpinner
        function NSpinnerValueChanged(app, event)
            app.setUp
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.setUp
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            app.animatePlot
        end

        % Button pushed function: StopAnimationButton
        function StopAnimationButtonPushed(app, event)
            app.stopAnimation = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComplexFourierSeriesUIFigure = uifigure('Visible', 'off');
            app.ComplexFourierSeriesUIFigure.Name = 'Complex Fourier Series';
            app.GridLayout = uigridlayout(app.ComplexFourierSeriesUIFigure);
            app.FrequencyDomainComplexFourierSeriesCoefficientsPanel = uipanel(app.GridLayout);
            app.FrequencyDomainComplexFourierSeriesCoefficientsPanel.Title = 'Frequency Domain: Complex Fourier Series Coefficients';
            app.GridLayout3 = uigridlayout(app.FrequencyDomainComplexFourierSeriesCoefficientsPanel);
            app.UIAxesRealCn = uiaxes(app.GridLayout3);
            title(app.UIAxesRealCn, 'Title')
            xlabel(app.UIAxesRealCn, 't')
            ylabel(app.UIAxesRealCn, 'y')
            zlabel(app.UIAxesRealCn, 'Z')
            app.UIAxesRealCn.XLim = [0 8];
            app.UIAxesRealCn.YLim = [-1 1];
            app.UIAxesImagCn = uiaxes(app.GridLayout3);
            title(app.UIAxesImagCn, 'Title')
            xlabel(app.UIAxesImagCn, 't')
            ylabel(app.UIAxesImagCn, 'y')
            zlabel(app.UIAxesImagCn, 'Z')
            app.UIAxesImagCn.XLim = [0 8];
            app.UIAxesImagCn.YLim = [-1 1];
            app.TimeDomainPanel = uipanel(app.GridLayout);
            app.TimeDomainPanel.Title = 'Time Domain';
            app.GridLayout4 = uigridlayout(app.TimeDomainPanel);
            app.UIAxesComplex = uiaxes(app.GridLayout4);
            title(app.UIAxesComplex, 'Title')
            xlabel(app.UIAxesComplex, 't')
            ylabel(app.UIAxesComplex, 'y')
            zlabel(app.UIAxesComplex, 'Z')
            app.UIAxesComplex.XLim = [0 1];
            app.UIAxesComplex.YLim = [-2 2];
            app.UIAxesComplex.ZLim = [0 1];
            app.UIAxesReal = uiaxes(app.GridLayout4);
            title(app.UIAxesReal, 'Title')
            xlabel(app.UIAxesReal, 't')
            ylabel(app.UIAxesReal, 'y')
            zlabel(app.UIAxesReal, 'Z')
            app.UIAxesReal.XLim = [0 1];
            app.UIAxesReal.YLim = [-2 2];
            app.UIAxesReal.ZLim = [0 1];
            app.UIAxesImag = uiaxes(app.GridLayout4);
            title(app.UIAxesImag, 'Title')
            xlabel(app.UIAxesImag, 't')
            ylabel(app.UIAxesImag, 'y')
            zlabel(app.UIAxesImag, 'Z')
            app.UIAxesImag.XLim = [0 1];
            app.UIAxesImag.YLim = [-2 2];
            app.UIAxesImag.ZLim = [0 1];
            app.UIAxesAnimate = uiaxes(app.GridLayout4);
            title(app.UIAxesAnimate, 'Title')
            xlabel(app.UIAxesAnimate, 'X')
            ylabel(app.UIAxesAnimate, 'Y')
            zlabel(app.UIAxesAnimate, 'Z')
            app.UIAxesAnimate.XLim = [0 1];
            app.UIAxesAnimate.YLim = [0 1];
            app.UIAxesAnimate.Visible = 'off';
            app.ControlsPanel = uipanel(app.GridLayout4);
            app.ControlsPanel.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsPanel);
            app.EqAxes = uiaxes(app.GridLayout2);
            app.EqAxes.Visible = 'off';
            app.FourierSeriesDefinitionPanel = uipanel(app.GridLayout2);
            app.FourierSeriesDefinitionPanel.Title = 'Fourier Series Definition';
            app.ClearButton = uibutton(app.GridLayout2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AnimateButton = uibutton(app.GridLayout2, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.NLabel = uilabel(app.GridLayout2);
            app.NLabel.Text = 'N:';
            app.NSpinner = uispinner(app.GridLayout2);
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NSpinnerValueChanged, true);
            app.NSpinner.Value = 5;
            app.StopAnimationButton = uibutton(app.GridLayout2, 'push');
            app.StopAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StopAnimationButtonPushed, true);
            app.StopAnimationButton.Visible = 'off';
            app.StopAnimationButton.Text = 'Stop Animation';
            % Show the figure after all components are created
            app.ComplexFourierSeriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComplexFourierSeries

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComplexFourierSeriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComplexFourierSeriesUIFigure)
        end
    end
end