classdef transformTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveFourierTransformTableUIFigure  matlab.ui.Figure
        FouriertransformdefinitionusedLabel  matlab.ui.control.Label
        aLabel                 matlab.ui.control.Label
        aSlider                matlab.ui.control.Slider
        VisualizationPanel     matlab.ui.container.Panel
        UIAxes_transform       matlab.ui.control.UIAxes
        UIAxes_function        matlab.ui.control.UIAxes
        FouriertransformLabel  matlab.ui.control.Label
        FunctionLabel          matlab.ui.control.Label
        FunctionButtonGroup    matlab.ui.container.ButtonGroup
        SincButton             matlab.ui.control.ToggleButton
        StepdecayButton        matlab.ui.control.ToggleButton
        SineButton             matlab.ui.control.ToggleButton
        UIAxes_latex3          matlab.ui.control.UIAxes
        UIAxes_latex2          matlab.ui.control.UIAxes
        UIAxes_latex           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NPoints = 500;
        axisLabelFontSize = 14;
        equationFontSize = 18;
        
        functionColorReal = [  0    0.4470    0.7410 ];
        functionColorImag = [  0.8500    0.3250    0.0980 ];
        transformColorReal = [ 0.9290    0.6940    0.1250];
        transformColorImag = [  0.4940    0.1840    0.5560];
        lineWidth = 1.2;
       
        
    end
    
    methods (Access = private)
        
        function writeLatex(app, latexString, axisObject, fontSize, colorIn)
            cla(axisObject)
            text(axisObject,0.5, 0.5, latexString,...
                'Interpreter',"latex",'FontSize',fontSize,...
                'HorizontalAlignment',"center",'VerticalAlignment',"middle",...
                'Color', colorIn)
        end
        
        function plotDirac(app, axisObject, xlocation, height, color )
            
            holdState = ishold(axisObject);
            hold(axisObject,'on')
            if (height > 0)
                stem(axisObject, xlocation, height, '-^', 'Color', color,...
                "LineWidth",app.lineWidth,'markerfacecolor', color);
            else
                stem(axisObject, xlocation, height, '-v', 'Color', color,...
                "LineWidth",app.lineWidth,'markerfacecolor', color);
            end
            
            plot(app.UIAxes_transform, xlocation, 0, 'o', 'markerfacecolor','white', 'Color', color, "LineWidth",app.lineWidth )
            
            if (~holdState)
                hold(axisObject,'off')
            end
        end
        
        function f = rect( app, t )
            
            f = heaviside(t+0.5) .* heaviside( 0.5 - t );
        
        end
        
        function realImagLabel(app)
            
            xlim = get(app.UIAxes_transform,'xlim');
            ylim = get(app.UIAxes_transform,'ylim');
            xpos = xlim(1) + (xlim(2) - xlim(1))*0.9;
            ypos = ylim(1) + (ylim(2) - ylim(1))*0.925;
            ypos2 = ylim(1) + (ylim(2) - ylim(1))*0.8;
            text(app.UIAxes_transform, xpos, ypos, 'real$\left(\hat{f}\right)$', 'Color', app.transformColorReal,...
               'FontSize',app.axisLabelFontSize,'Interpreter','Latex');
            text(app.UIAxes_transform, xpos, ypos2, 'imag$\left(\hat{f}\right)$', 'Color', app.transformColorImag,...
               'FontSize',app.axisLabelFontSize,'Interpreter','Latex');
           
            xlim = get(app.UIAxes_function,'xlim');
            ylim = get(app.UIAxes_function,'ylim');
            xpos = xlim(1) + (xlim(2) - xlim(1))*0.9;
            ypos = ylim(1) + (ylim(2) - ylim(1))*0.925;
            ypos2 = ylim(1) + (ylim(2) - ylim(1))*0.8;
            text(app.UIAxes_function, xpos, ypos, 'real$(f)$', 'Color', app.functionColorReal,...
               'FontSize',app.axisLabelFontSize,'Interpreter','Latex');
            text(app.UIAxes_function, xpos, ypos2, 'imag$(f)$', 'Color', app.functionColorImag,...
               'FontSize',app.axisLabelFontSize,'Interpreter','Latex');
        end
        
        
        function plotSine(app)
            
            tLim = [-2*pi,2*pi];
            omegaLim = [-4,4];
            a = app.aSlider.Value;
            
            % Create latex functions
            app.writeLatex('$$f(t) = \sin (a t) $$',...
                                   app.UIAxes_latex, app.equationFontSize, [0,0,0]);
            app.writeLatex('$$\hat{f}(\omega) = -i \pi [ \delta(\omega - a) - \delta(\omega + a) ]$$',...
                  app.UIAxes_latex2, app.equationFontSize, [0,0,0]);
            
            xlim(app.UIAxes_function, tLim );
            ylim(app.UIAxes_function, [-1.2,1.2] );
            t = linspace(tLim(1),tLim(2),app.NPoints);
            hold(app.UIAxes_function,'on');
            plot(app.UIAxes_function, t, sin(t*a), 'Color', app.functionColorReal,...
                "LineWidth",app.lineWidth);
            plot(app.UIAxes_function, t, t.*0, 'Color', app.functionColorImag,...
                "LineWidth",app.lineWidth);
            
            xlim(app.UIAxes_transform, omegaLim);
            ylim(app.UIAxes_transform, [-4,4] );
            omega = [-a, a];
            fhat_imag = [pi, -pi];
            
            plot(app.UIAxes_transform, omegaLim, [0,0], 'Color', app.transformColorImag, "LineWidth",app.lineWidth )
            plotDirac(app, app.UIAxes_transform, omega(1), fhat_imag(1), app.transformColorImag );
            plotDirac(app, app.UIAxes_transform, omega(2), fhat_imag(2), app.transformColorImag );

            app.realImagLabel;
           

        end
        
              
        function plotStepDecay(app)
            
            tLim = [-3, 3];
            omegaLim = [-4,4];
            fhatLim = [-0.75,1.25];
            
            % Create latex functions
            app.writeLatex('$$f(t) = e^{-at} u(t)$$ {where $u(t)$ is Heaviside step function} ',...
                                   app.UIAxes_latex, app.equationFontSize, [0,0,0]);
            app.writeLatex('$$\hat{f}(\omega) = \frac{1}{a + i \omega}$$',...
                  app.UIAxes_latex2, app.equationFontSize, [0,0,0]);
            
            xlim(app.UIAxes_function, tLim );
            ylim(app.UIAxes_function, [-1.2,1.2] );
            t = linspace(tLim(1),tLim(2),app.NPoints);
            a = app.aSlider.Value;
            f = heaviside(t).*exp(-a.*t);
            
            hold(app.UIAxes_function,'on');
            plot(app.UIAxes_function, t, f, 'Color', app.functionColorReal,...
                "LineWidth",app.lineWidth);
            plot(app.UIAxes_function, t, t.*0, 'Color', app.functionColorImag,...
                "LineWidth",app.lineWidth);   
            
            xlim(app.UIAxes_transform, omegaLim);
            ylim(app.UIAxes_transform, fhatLim);
            omega = linspace(omegaLim(1),omegaLim(2),app.NPoints);
            fhat = 1./(a + 1i.*omega);
           
            hold(app.UIAxes_transform,'on');
            
            
            plot( app.UIAxes_transform, omega, real(fhat), 'Color', app.transformColorReal,...
                "LineWidth",app.lineWidth);
            plot( app.UIAxes_transform, omega, imag(fhat), 'Color', app.transformColorImag,...
                "LineWidth",app.lineWidth);

            app.realImagLabel;
           

        end
        
      
        function plotSinc(app)
            
            tLim = [-6, 6];
            flim = [-0.5,1.5];
            omegaLim = [-6,6];
            fhatLim = [-0.25,3];
            
            % Create latex functions
            app.writeLatex('$f(t) =$ sinc$(at)$ ',...
                                   app.UIAxes_latex, app.equationFontSize, [0,0,0]);
            app.writeLatex('$\hat{f}(\omega) =  \frac{1}{|a|}$ rect$\left( \frac{\omega}{2 \pi a} \right) $',...
                  app.UIAxes_latex2, app.equationFontSize, [0,0,0]);
            
            xlim(app.UIAxes_function, tLim );
            ylim(app.UIAxes_function, flim );
            t = linspace(tLim(1),tLim(2),app.NPoints);
            a = app.aSlider.Value;
            f = sinc(a.*t);
            hold(app.UIAxes_function,'on');
            plot(app.UIAxes_function, t, f, 'Color', app.functionColorReal,...
                "LineWidth",app.lineWidth);
            plot(app.UIAxes_function, t, t.*0, 'Color', app.functionColorImag,...
                "LineWidth",app.lineWidth);  
            
            xlim(app.UIAxes_transform, omegaLim);
            ylim(app.UIAxes_transform, fhatLim);
            omega = linspace(omegaLim(1),omegaLim(2),app.NPoints);
            fhat = 1./abs(a) .* app.rect( omega./(2.*pi.*a));
           
            hold(app.UIAxes_transform,'on');
            
            
            plot( app.UIAxes_transform, omega, real(fhat), 'Color', app.transformColorReal,...
                "LineWidth",app.lineWidth);
            plot( app.UIAxes_transform, omega, imag(fhat), 'Color', app.transformColorImag,...
                "LineWidth",app.lineWidth);

            app.realImagLabel;
           

        end
        
        function generatePlot(app)
            
            selectedButton = app.FunctionButtonGroup.SelectedObject;
            
            cla(app.UIAxes_transform);
            cla(app.UIAxes_function);

            if(  strcmp(selectedButton.Text,'Sine') )
                app.plotSine();
            
            elseif ( strcmp(selectedButton.Text,'Step decay') )
                
                app.plotStepDecay();
                
            elseif ( strcmp(selectedButton.Text,'Sinc') )
            
                app.plotSinc();
            end      
            
        end
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              
              axis(app.UIAxes_latex,'off')
              axis(app.UIAxes_latex2,'off')
              axis(app.UIAxes_latex3,'off')
              
              
              ylabel(app.UIAxes_function, '$f(t)$', 'interpreter','latex', 'FontSize', app.axisLabelFontSize);
              xlabel(app.UIAxes_function, '$t$', 'interpreter','latex', 'FontSize', app.axisLabelFontSize);
              ylabel(app.UIAxes_transform, '$\hat{f}(\omega)$', 'interpreter','latex', 'FontSize', app.axisLabelFontSize);
              xlabel(app.UIAxes_transform, '$\omega$', 'interpreter','latex', 'FontSize', app.axisLabelFontSize);
  

              app.writeLatex(['$$\hat{f}(\omega) = \int_{-\infty}^\infty f(t) e^{-i\omega t} dt$$ ',...
                             '$$f(t) = \frac{1}{2\pi} \int_{-\infty}^\infty \hat{f}(\omega) e^{i\omega t} d\omega$$'],...
                                   app.UIAxes_latex3, app.equationFontSize, [0,0,0]);
                               
              
              app.plotSine();
               
        end

        % Selection changed function: FunctionButtonGroup
        function FunctionButtonGroupSelectionChanged(app, event)
            
            
            app.generatePlot();
            
            
        end

        % Callback function
        function omegaSliderValueChanged(app, event)
            value = app.omegaSlider.Value;
            app.generatePlot();
            
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            value = app.aSlider.Value;
            app.generatePlot();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveFourierTransformTableUIFigure = uifigure('Visible', 'off');
            app.InteractiveFourierTransformTableUIFigure.Name = 'Interactive Fourier Transform Table';
            app.UIAxes_latex = uiaxes(app.InteractiveFourierTransformTableUIFigure);
            app.UIAxes_latex.XTick = [];
            app.UIAxes_latex.YTick = [];
            app.UIAxes_latex2 = uiaxes(app.InteractiveFourierTransformTableUIFigure);
            app.UIAxes_latex2.XTick = [];
            app.UIAxes_latex2.YTick = [];
            app.UIAxes_latex3 = uiaxes(app.InteractiveFourierTransformTableUIFigure);
            app.UIAxes_latex3.XTick = [];
            app.UIAxes_latex3.YTick = [];
            app.FunctionButtonGroup = uibuttongroup(app.InteractiveFourierTransformTableUIFigure);
            app.FunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FunctionButtonGroupSelectionChanged, true);
            app.FunctionButtonGroup.Title = 'Function';
            app.SineButton = uitogglebutton(app.FunctionButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.StepdecayButton = uitogglebutton(app.FunctionButtonGroup);
            app.StepdecayButton.Text = 'Step decay';
            app.SincButton = uitogglebutton(app.FunctionButtonGroup);
            app.SincButton.Text = 'Sinc';
            app.FunctionLabel = uilabel(app.InteractiveFourierTransformTableUIFigure);
            app.FunctionLabel.Text = 'Function';
            app.FouriertransformLabel = uilabel(app.InteractiveFourierTransformTableUIFigure);
            app.FouriertransformLabel.Text = 'Fourier transform';
            app.VisualizationPanel = uipanel(app.InteractiveFourierTransformTableUIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes_function = uiaxes(app.VisualizationPanel);
            xlabel(app.UIAxes_function, 't')
            ylabel(app.UIAxes_function, 'f(t)')
            app.UIAxes_transform = uiaxes(app.VisualizationPanel);
            xlabel(app.UIAxes_transform, '\omega')
            ylabel(app.UIAxes_transform, 'F(\omega)')
            app.aSlider = uislider(app.InteractiveFourierTransformTableUIFigure);
            app.aSlider.Limits = [0 5];
            app.aSlider.MajorTicks = [0 5];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.aSlider.Value = 1;
            app.aLabel = uilabel(app.InteractiveFourierTransformTableUIFigure);
            app.aLabel.Text = 'a';
            app.FouriertransformdefinitionusedLabel = uilabel(app.InteractiveFourierTransformTableUIFigure);
            app.FouriertransformdefinitionusedLabel.Text = 'Fourier transform definition used';
            % Show the figure after all components are created
            app.InteractiveFourierTransformTableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveFourierTransformTableUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveFourierTransformTableUIFigure)
        end
    end
end